classdef Concavidad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        f5                              matlab.ui.control.EditField
        Concavidadenx2Label             matlab.ui.control.Label
        f4                              matlab.ui.control.EditField
        Concavidadenx1Label             matlab.ui.control.Label
        TeoremaconcavidadyelcriteriodelasegundaderivadaLabel  matlab.ui.control.Label
        x2                              matlab.ui.control.NumericEditField
        Ingresex2EditFieldLabel         matlab.ui.control.Label
        x1                              matlab.ui.control.NumericEditField
        Ingresex1EditFieldLabel         matlab.ui.control.Label
        Calcular                        matlab.ui.control.Button
        f3                              matlab.ui.control.EditField
        PuntodeinflexionEditFieldLabel  matlab.ui.control.Label
        f2                              matlab.ui.control.EditField
        SegundaderivadaEditFieldLabel   matlab.ui.control.Label
        f1                              matlab.ui.control.EditField
        PrimeraderivadaEditFieldLabel   matlab.ui.control.Label
        ResultadosLabel                 matlab.ui.control.Label
        funcion                         matlab.ui.control.EditField
        IngreselaFuncionEditFieldLabel  matlab.ui.control.Label
        axes                            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Calcular
        function CalcularPushed(app, event)

            syms x;
            f=app.funcion.Value;
            fun = str2sym(f);
            fp= diff(fun,x);
            app.f1.Value = string(fp);
            fpp=diff (fun,2)
            app.f2.Value = string(fpp);
            inflex = solve (fpp);
            app.f3.Value = string(inflex);
            x1= app.x1.Value;
            x2= app.x2.Value;
            sus1 = subs(fpp,x,x1);
            app.f4.Value = string(sus1);
            sus2 = subs(fpp,x,x2);
            app.f5.Value = string(sus2);
            g = sym2poly(fun);
            x= linspace(x1,x2,10);
            y = polyval(g,x);
            plot(app.axes,x,y,'b-');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Grafica')
            xlabel(app.axes, 'X')
            ylabel(app.axes, 'Y')
            zlabel(app.axes, 'Z')
            app.IngreselaFuncionEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselaFuncionEditFieldLabel.Tag = 'funcion';
            app.IngreselaFuncionEditFieldLabel.Text = 'Ingrese la Funcion';
            app.funcion = uieditfield(app.UIFigure, 'text');
            app.funcion.Tag = 'funcion';
            app.ResultadosLabel = uilabel(app.UIFigure);
            app.ResultadosLabel.Text = 'Resultados';
            app.PrimeraderivadaEditFieldLabel = uilabel(app.UIFigure);
            app.PrimeraderivadaEditFieldLabel.Text = 'Primera derivada';
            app.f1 = uieditfield(app.UIFigure, 'text');
            app.f1.Tag = 'f1';
            app.SegundaderivadaEditFieldLabel = uilabel(app.UIFigure);
            app.SegundaderivadaEditFieldLabel.Text = 'Segunda derivada';
            app.f2 = uieditfield(app.UIFigure, 'text');
            app.f2.Tag = 'f2';
            app.PuntodeinflexionEditFieldLabel = uilabel(app.UIFigure);
            app.PuntodeinflexionEditFieldLabel.Text = 'Punto de inflexion';
            app.f3 = uieditfield(app.UIFigure, 'text');
            app.f3.Tag = 'f3';
            app.Calcular = uibutton(app.UIFigure, 'push');
            app.Calcular.ButtonPushedFcn = createCallbackFcn(app, @CalcularPushed, true);
            app.Calcular.Tag = 'calcular';
            app.Calcular.Text = {'Calcular'; ''};
            app.Ingresex1EditFieldLabel = uilabel(app.UIFigure);
            app.Ingresex1EditFieldLabel.Text = 'Ingrese x1';
            app.x1 = uieditfield(app.UIFigure, 'numeric');
            app.Ingresex2EditFieldLabel = uilabel(app.UIFigure);
            app.Ingresex2EditFieldLabel.Text = 'Ingrese x2';
            app.x2 = uieditfield(app.UIFigure, 'numeric');
            app.TeoremaconcavidadyelcriteriodelasegundaderivadaLabel = uilabel(app.UIFigure);
            app.TeoremaconcavidadyelcriteriodelasegundaderivadaLabel.Text = {'Teorema concavidad y el criterio de la segunda derivada'; ''};
            app.Concavidadenx1Label = uilabel(app.UIFigure);
            app.Concavidadenx1Label.Text = 'Concavidad en x1';
            app.f4 = uieditfield(app.UIFigure, 'text');
            app.f4.Tag = 'f3';
            app.Concavidadenx2Label = uilabel(app.UIFigure);
            app.Concavidadenx2Label.Text = 'Concavidad en x2';
            app.f5 = uieditfield(app.UIFigure, 'text');
            app.f5.Tag = 'f3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Concavidad

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end